classdef filter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltersGUIUIFigure              matlab.ui.Figure
        openbutton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        UIAxes_2                        matlab.ui.control.UIAxes
        PSNRvaluefilterEditFieldLabel   matlab.ui.control.Label
        PSNRvaluefilterEditField        matlab.ui.control.NumericEditField
        SaveDatainExcelButton           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ApplyFilterButton               matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SegmentationDropDownLabel       matlab.ui.control.Label
        SegmentationDropDown            matlab.ui.control.DropDown
        PSNRvaluesegmentationEditFieldLabel  matlab.ui.control.Label
        PSNRvaluesegmentationEditField  matlab.ui.control.NumericEditField
        ApplysegmentationButton         matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        save_images=0; % Description
        save_all_images=0;
    end
    
    properties (Access = private)
        file_name=''; % Description
    end
    
    methods (Access = public)
        
       function x = LoG_filter(~,img,step_size,sigma)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%function to apply 2D gaussian filter to images
% hsize: filter step-size
% sigma: sigma value for gaussian function
        if nargin < 2
            error('the function requires atleast one argument: image');
        end

        if nargin < 3
            step_size = [5 5]; %default value
            sigma = 0.3 ;        %default value
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
% subplot(1,2,1),imshow(img),title('original image')


            img = double(img);
            [r c]= size(img);
            img_smooth = zeros(size(img));
            G = fspecial('log',step_size,sigma);
            min_r = floor(size(G,1)/2);
            min_c = floor(size(G,2)/2);
            max_r = r - min_r;
           max_c = c - min_c;

        for i = (1+min_r):max_r
                for j = (1+min_c):max_c
                    row_min = i - min_r;
                    row_max = i + min_r;
                    col_min = j - min_c;
                    col_max = j + min_c;
                    template = double(img(row_min:row_max, col_min : col_max));
                    img_smooth(i,j) = sum(sum(G .* template));
                end
        end

        x = uint8(img_smooth);

       end
       
       function y = NAFSM(app,x)

        x = padarray(x,[3 3],'replicate');
        x = double(x);

        M = x;
        y =x;

        N = zeros(size(x));
        N(x~=0 & x~=255) = 1;

        smax = 3;
        
        [xlen ylen] =size(x);
        T1 = 10;
        T2 = 30;
        
        
        for i=4:1:xlen-3
            for j=4:1:ylen-3
                
                if N(i,j) == 0
                    s = 1;
                    g = N(i-s:i+s,j-s:j+s);
                    
                    while sum(g(:)<1) && s<smax
                        s = s+1;
                        g = N(i-s:i+s,j-s:j+s);
                    end % while
                    
                    if s<=smax && sum(g(:)>0)
                        
                       clear tmp;
                       tmp = x(i-s:i+s,j-s:j+s);
                       tmp = tmp(g(:)==1);
                       
                       M(i,j) = median(tmp(:));
                       
                    else
                        
                       M(i,j) = median([x(i-1,j-1),x(i,j-1),x(i+1,j-1),x(i-1,j)]);
                       
                    end % if s>smax
                    
                end % if N(i,j) == 0
            end
        end % for i=4:1:xlen-3
        
        for i=4:1:xlen-3
            for j=4:1:ylen-3
                
                if N(i,j) == 0
                    clear tmp;
                    tmp = abs(x(i-1:i+1,j-1:j+1)-x(i,j)*ones(3,3));
        
                    d = max(tmp(:));
                    
                    if d<T1
                        f = 0;
                    else
                        if d>=T1 && d<T2
                            f = (d-T1)/(T2-T1);
                        else
                            f =1;
                        end
                    end % d<T1
                    
                    y(i,j) = (1-f)*x(i,j)+f*M(i,j);
                end %  if N(i,j) == 0
                
            end
        end % for i=4:1:xlen-3
        
        y = uint8(y(4:xlen-3,4:ylen-3));
        
       end
       
       function [ Y ] = TMED(app,X, h )
       
        
        if nargin < 3
            h=3;    % local search window size: h=3; h=5; h=7; h=9; h=11;
            f=1;    % padding value for window: f=1; f=2; f=3; f=4; f=5;
        else
            f=(h-1)/2;    % padding value for window: f=1; f=2; f=3; f=4; f=5;
        end
        F = zeros(h*h,1);
        Y  = zeros(size(X));
        
        
        X = double(padarray(X,[f f],'symmetric'));
        [m n ~] = size(X);
        
        for i=1+f:1:m-f
           for j=1+f:1:n-f
               x=reshape(X(i-f:i+f, j-f:j+f),[],1);
               xmax = max(x);
               xmin = min(x);
               xmed = median(x);
               xmm = max(xmax-xmed, xmed-xmin);
               
               F(:,:) = 0;
               if xmm==0
                   F(:,:) = 1;
               else
                   F = (abs(x-xmed)/xmm);
               end
                
               Y(i-f,j-f) = sum(sum(F.*x))/sum(sum(F));
               clear xmax xmin xmed xmm;
        
             end
            end
        end
        
        function [ Y ] = ATMED(app, X, h )


        if nargin < 3
            h=3;    % local search window size: h=3; h=5; h=7; h=9; h=11;
            f=1;    % padding value for window: f=1; f=2; f=3; f=4; f=5;
        else
            f=(h-1)/2;    % padding value for window: f=1; f=2; f=3; f=4; f=5;
        end
        F = zeros(h*h,1);
        Y  = zeros(size(X));
        
        X = double(padarray(X,[f f],'symmetric'));
        [m n ~] = size(X);
        
        for i=1+f:1:m-f
           for j=1+f:1:n-f
               x=reshape(X(i-f:i+f, j-f:j+f),[],1);
               xmax = max(x);
               xmin = min(x);
               xmed = median(x);       
               
               F(:,:) = 0;
               if (xmed-xmin==0)||(xmax-xmed==0)
                   F(:,:) = 1;
               else
                   ind1 = find((x>=xmin)&(x<=xmed));
                   F(ind1) = 1-(xmed-x(ind1))/(xmed-xmin);
                   
                   ind2 = find((x>=xmed)&(x<=xmax));
                   F(ind2) = 1-(x(ind2)-xmed)/(xmax-xmed);
               end
                
               Y(i-f,j-f) = sum(sum(F.*x))/sum(sum(F));
               clear xmax xmin xmed;
           end
        end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openbutton
        function openbuttonButtonPushed(app, event)
            [filename,path] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'},'Select an image');
            fullpath=strcat(path,filename);
            img=imread(fullpath);
            imshow(img,'parent',app.UIAxes);
            app.EditField.Value=fullpath;
            app.Label.Text = 'The selected image is opened';
            app.SaveDatainExcelButton.Enable=true;
            %app.ApplyAllButton.Enable=true;
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            img=findobj(app.UIAxes, 'type', 'image');
            img=img.CData;

            value=app.FilterDropDown.Value;
            
            switch value
                case 'Mean'
                     h=fspecial('average',[3 3]);
                     l=imfilter(img,h);
                     imshow(l,'parent',app.UIAxes_2);
                     peaksnr=psnr(l,img);
                case 'Median' 
                    m=medfilt2(img);
                    imshow(m,'parent',app.UIAxes_2);
                    peaksnr=psnr(m,img);
                case 'Gaussian'
                    g=imgaussfilt(img);
                    imshow(g,'parent',app.UIAxes_2);
                    peaksnr=psnr(g,img);
                case 'Wiener'
                     w=wiener2(img);
                     imshow(w,'parent',app.UIAxes_2);
                     peaksnr=psnr(w,img); 
                case 'LoG'
                    l=LoG_filter(app,img);
                    imshow(l,'parent',app.UIAxes_2);
                    peaksnr=psnr(l,img); 
                case 'NAFSM'
                    n=NAFSM(app,img);
                    imshow(n,'parent',app.UIAxes_2);
                    peaksnr=psnr(n,img);  
                case 'ATMED'
                    f=ATMED(app,img);
                    
                    imshow(f,[0 500],'parent',app.UIAxes_2);
                    peaksnr=psnr(uint8(f),img);  
                case 'TMED'
                    f=TMED(app,img);
                    imshow(f,[0 500],'parent',app.UIAxes_2);
                    peaksnr=psnr(uint8(f),img);  
            end
            app.PSNRvaluefilterEditField.Value=peaksnr;
            app.Label.Text="The filter "+value+ " is applied";
        end

        % Button pushed function: SaveDatainExcelButton
        function SaveDatainExcelButtonPushed(app, event)

                img=findobj(app.UIAxes, 'type', 'image');
                img=img.CData;
                
                img2=findobj(app.UIAxes_2, 'type', 'image');
                img2=img2.CData;
                
                img3=findobj(app.UIAxes_3, 'type', 'image');
                img3=img3.CData;
                
                imwrite(img2,'D:\filt.jpg');
                imwrite(img3,'D:\filt2.jpg');
                
                if app.file_name==""
                     [filename,path] = uigetfile({'*.xlsx'},'Select an excel file');
                     app.file_name=strcat(path,filename);
                     
                     xlswrite(app.file_name,cellstr("File Name"),'Sheet1','A1');
                     xlswrite(app.file_name,cellstr("Orginal Image"),'Sheet1','B1');
                     xlswrite(app.file_name,cellstr("Filtered Image"),'Sheet1','C1');
                     xlswrite(app.file_name,cellstr("Filter used"),'Sheet1','D1');
                     xlswrite(app.file_name,cellstr("PSNR value"),'Sheet1','E1');
                     xlswrite(app.file_name,cellstr("Segmented Image"),'Sheet1','F1');
                     xlswrite(app.file_name,cellstr("PSNR value"),'Sheet1','G1');
                     
                     xlswrite(app.file_name,cellstr("File Name"),'Sheet2','A1');
                     xlswrite(app.file_name,cellstr("Orginal Image"),'Sheet2','B1');
                     
                     xlswrite(app.file_name,cellstr("Mean"),'Sheet2','C1');
                     xlswrite(app.file_name,cellstr("Mean Otsu"),'Sheet2','D1');
                     xlswrite(app.file_name,cellstr("Mean PSNR"),'Sheet2','E1');
                     xlswrite(app.file_name,cellstr("Mean Otsu PSNR"),'Sheet2','F1');
                     xlswrite(app.file_name,cellstr("Mean watershed"),'Sheet2','G1');
                     xlswrite(app.file_name,cellstr("Mean Watershed PSNR"),'Sheet2','H1');
                     
                     xlswrite(app.file_name,cellstr("Median"),'Sheet2','I1');
                     xlswrite(app.file_name,cellstr("Median Otsu"),'Sheet2','J1');
                     xlswrite(app.file_name,cellstr("Median PSNR"),'Sheet2','K1');
                     xlswrite(app.file_name,cellstr("Median Otsu PSNR"),'Sheet2','L1');
                     xlswrite(app.file_name,cellstr("Median watershed"),'Sheet2','M1');
                     xlswrite(app.file_name,cellstr("Median Watershed PSNR"),'Sheet2','N1');
                     
                     
                     xlswrite(app.file_name,cellstr("Gauss"),'Sheet2','O1');
                     xlswrite(app.file_name,cellstr("Gauss Otsu"),'Sheet2','P1');
                     xlswrite(app.file_name,cellstr("Gauss PSNR"),'Sheet2','Q1');
                     xlswrite(app.file_name,cellstr("Gauss Otsu PSNR"),'Sheet2','R1');
                     xlswrite(app.file_name,cellstr("Gauss watershed"),'Sheet2','S1');
                     xlswrite(app.file_name,cellstr("Gauss Watershed PSNR"),'Sheet2','T1');
                     
                     
                     xlswrite(app.file_name,cellstr("Wiener"),'Sheet2','U1');
                     xlswrite(app.file_name,cellstr("Wiener Otsu"),'Sheet2','V1');
                     xlswrite(app.file_name,cellstr("Wiener PSNR"),'Sheet2','W1');
                     xlswrite(app.file_name,cellstr("Wiener Otsu PSNR"),'Sheet2','X1');
                     xlswrite(app.file_name,cellstr("Wiener watershed"),'Sheet2','Y1');
                     xlswrite(app.file_name,cellstr("Wiener Watershed PSNR"),'Sheet2','Z1');
                     
                     
                     xlswrite(app.file_name,cellstr("LoG"),'Sheet2','AA1');
                     xlswrite(app.file_name,cellstr("LoG Otsu"),'Sheet2','AB1');
                     xlswrite(app.file_name,cellstr("LoG PSNR"),'Sheet2','AC1');
                     xlswrite(app.file_name,cellstr("LoG Otsu PSNR"),'Sheet2','AD1');
                     xlswrite(app.file_name,cellstr("LoG watershed"),'Sheet2','AE1');
                     xlswrite(app.file_name,cellstr("LoG Watershed PSNR"),'Sheet2','AF1');
                     
                     
                     xlswrite(app.file_name,cellstr("NAFSM"),'Sheet2','AG1');
                     xlswrite(app.file_name,cellstr("NAFSM Otsu"),'Sheet2','AH1');
                     xlswrite(app.file_name,cellstr("NAFSM PSNR"),'Sheet2','AI1');
                     xlswrite(app.file_name,cellstr("NAFSM Otsu PSNR"),'Sheet2','AJ1');
                     xlswrite(app.file_name,cellstr("NAFSM watershed"),'Sheet2','AK1');
                     xlswrite(app.file_name,cellstr("NAFSM Watershed PSNR"),'Sheet2','AL1');
                     
                     
                end
                
                
                Excel = actxserver('Excel.Application');
%                 set(Excel,'Visible',1);
                Workbooks = Excel.Workbooks;
%                 Excel.Visible=1;
                
                Workbook = Workbooks.Open(app.file_name);
                Sheets = Excel.ActiveWorkBook.Sheets;
                Sheet1 = get(Sheets, 'Item', 1);

                file=convertCharsToStrings(app.EditField.Value).split('\');
                fname=file(end);
                
                if app.save_images==0
                    my_ran=Sheet1.Range('A:A');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('B:B');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('C:C');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('D:D');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('E:E');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('F:F');
                    my_ran.ColumnWidth=13.57;
                    
                    my_ran=Sheet1.Range('G:G');
                    my_ran.ColumnWidth=13.57;
                    
                    
                end
            
                cell_no=app.save_images+2;
                cell_str=num2str(cell_no);
                cell_str=strcat(cell_str,':',cell_str);
                my_ran=Sheet1.Range(cell_str);
                my_ran.RowHeight=75;
                
                file_range=strcat('A',num2str(cell_no));
                filter_range=strcat('D',num2str(cell_no));
                psnr_range=strcat('E',num2str(cell_no));
                psnr_range_seg=strcat('G',num2str(cell_no));
                invoke(Workbook, 'Save');
                invoke(Excel, 'Quit'); 
                
                xlswrite(app.file_name,cellstr(fname),'Sheet1',file_range);
                xlswrite(app.file_name,cellstr(app.FilterDropDown.Value),'Sheet1',filter_range);
                xlswrite(app.file_name,app.PSNRvaluefilterEditField.Value,'Sheet1',psnr_range);
                xlswrite(app.file_name,app.PSNRvaluesegmentationEditField.Value,'Sheet1',psnr_range_seg);
                
                
                Excel = actxserver('Excel.Application');
                Workbooks = Excel.Workbooks;
                
                Workbook = Workbooks.Open(app.file_name);
                Sheets = Excel.ActiveWorkBook.Sheets;
                Sheet1 = get(Sheets, 'Item', 1);
                Shapes = Sheet1.Shapes;

                Shapes.AddPicture(app.EditField.Value,0,1,75,15+(app.save_images*75),75,75);
                Shapes.AddPicture('D:\filt.jpg',0,1,150,15+(app.save_images*75),75,75);               
                Shapes.AddPicture('D:\filt2.jpg',0,1,300,15+(app.save_images*75),75,75);  
                invoke(Workbook, 'Save');
                invoke(Excel, 'Quit');     
                
                app.save_images=app.save_images+1;
        end

        % Callback function
        function ApplyAllButtonPushed(app, event)
                img=findobj(app.UIAxes, 'type', 'image');
                img=img.CData;
                
                if app.file_name==""
                     [filename,path] = uigetfile({'*.xlsx'},'Select an excel file');
                     app.file_name=strcat(path,filename);
                     
                     xlswrite(app.file_name,cellstr("File Name"),'Sheet1','A1');
                     xlswrite(app.file_name,cellstr("Orginal Image"),'Sheet1','B1');
                     xlswrite(app.file_name,cellstr("Filtered Image"),'Sheet1','C1');
                     xlswrite(app.file_name,cellstr("Filter used"),'Sheet1','D1');
                     xlswrite(app.file_name,cellstr("PSNR value"),'Sheet1','E1');
                     
                     xlswrite(app.file_name,cellstr("File Name"),'Sheet2','A1');
                     xlswrite(app.file_name,cellstr("Orginal Image"),'Sheet2','B1');
                     xlswrite(app.file_name,cellstr("Mean"),'Sheet2','C1');
                     xlswrite(app.file_name,cellstr("Mean PSNR"),'Sheet2','D1');
                     xlswrite(app.file_name,cellstr("Median"),'Sheet2','E1');
                     xlswrite(app.file_name,cellstr("Median PSNR"),'Sheet2','F1');
                     xlswrite(app.file_name,cellstr("Gauss"),'Sheet2','G1');
                     xlswrite(app.file_name,cellstr("Gauss PSNR"),'Sheet2','H1');
                     xlswrite(app.file_name,cellstr("Wiener"),'Sheet2','I1');
                     xlswrite(app.file_name,cellstr("Wiener PSNR"),'Sheet2','J1');
                     xlswrite(app.file_name,cellstr("LoG"),'Sheet2','K1');
                     xlswrite(app.file_name,cellstr("LoG PSNR"),'Sheet2','L1');
                     xlswrite(app.file_name,cellstr("NAFSM"),'Sheet2','M1');
                     xlswrite(app.file_name,cellstr("NAFSM PSNR"),'Sheet2','N1');
                end
                
                
                Excel = actxserver('Excel.Application');
                Workbooks = Excel.Workbooks;
                Workbook = Workbooks.Open(app.file_name);
                Sheets = Excel.ActiveWorkBook.Sheets;
                Sheet1 = get(Sheets, 'Item', 2);
                
                file=convertCharsToStrings(app.EditField.Value).split('\');
                fname=file(end);
            
                arr=['A','B','C','D','E','F','G','H','I','J','K'];
                
                if app.save_all_images==0
                    for i=1:11
                        a=strcat(arr(i),':',arr(i));
                        my_ran=Sheet1.Range(a);
                        my_ran.ColumnWidth=13.57;
                    end
                end
                
                cell_no=app.save_all_images+2;
                cell_str=num2str(cell_no);
                cell_str=strcat(cell_str,':',cell_str);
                my_ran=Sheet1.Range(cell_str);
                my_ran.RowHeight=75;
                
                file_range=strcat('A',num2str(cell_no));
                mean_psnr=strcat('D',num2str(cell_no));
                med_psnr=strcat('F',num2str(cell_no));
                gauss_psnr=strcat('H',num2str(cell_no));
                wie_psnr=strcat('J',num2str(cell_no));
                log_psnr=strcat('L',num2str(cell_no));
                naf_psnr=strcat('N',num2str(cell_no));
                
                
                invoke(Workbook, 'Save');
                invoke(Excel, 'Quit'); 
                
                xlswrite(app.file_name,cellstr(fname),'Sheet2',file_range);
                
                Excel = actxserver('Excel.Application');
                Workbooks = Excel.Workbooks;
                Workbook = Workbooks.Open(app.file_name);
                Sheets = Excel.ActiveWorkBook.Sheets;
                Sheet1 = get(Sheets, 'Item', 2);
                Shapes=Sheet1.Shapes;

                num=90;
                Shapes.AddPicture(app.EditField.Value,0,1,num,15+(app.save_all_images*75),75,75);
                num=num+75;
                
                h=fspecial('average',[3 3]);
                img2=imfilter(img,h);
                mean_p=psnr(img2,img); 
                imwrite(img2,'D:\filt.jpg');
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);

                img2=medfilt2(img);
                imwrite(img2,'D:\filt.jpg');
                med_p=psnr(img2,img);
                num=num+150;
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);
                
                img2=imgaussfilt(img);
                imwrite(img2,'D:\filt.jpg');
                gauss_p=psnr(img2,img);
                num=num+150;
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);
                
                img2=wiener2(img);
                imwrite(img2,'D:\filt.jpg');
                wie_p=psnr(img2,img);
                num=num+150;
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);
                
                
                img2=LoG_filter(app,img);
                imwrite(img2,'D:\filt.jpg');
                log_p=psnr(img2,img);
                num=num+150;
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);
                
                img2=NAFSM(app,img);
                imwrite(img2,'D:\filt.jpg');
                naf_p=psnr(img2,img);
                num=num+150;
                Shapes.AddPicture('D:\filt.jpg',0,1,num,15+(app.save_all_images*75),75,75);
                invoke(Workbook, 'Save');
                invoke(Excel, 'Quit'); 
                
                xlswrite(app.file_name,mean_p,'Sheet2',mean_psnr);
                xlswrite(app.file_name,med_p,'Sheet2',med_psnr);
                xlswrite(app.file_name,gauss_p,'Sheet2',gauss_psnr);
                xlswrite(app.file_name,wie_p,'Sheet2',wie_psnr);
                xlswrite(app.file_name,naf_p,'Sheet2',naf_psnr);
                xlswrite(app.file_name,log_p,'Sheet2',log_psnr);

                app.Label.Text='All filters values applied and stored!';
                app.save_all_images=app.save_all_images+1;
        end

        % Button pushed function: ApplysegmentationButton
        function ApplysegmentationButtonPushed(app, event)
            img=findobj(app.UIAxes_2, 'type', 'image');
            img=img.CData;
            
            seg_val=app.SegmentationDropDown.Value;
            
            switch seg_val
                case 'Global/Otsu'
                    level=graythresh(img);
                    bw=imbinarize(img,level);
                    if app.FilterDropDown.Value=="ATMED" || app.FilterDropDown.Value=="TMED"
                        imshow(bw,[0 700],'parent',app.UIAxes_3);
                    else
                        imshow(bw,'parent',app.UIAxes_3);
                    end
                    peaksnr=psnr(uint8(bw),uint8(img));
                    app.PSNRvaluesegmentationEditField.Value=peaksnr;
                case 'Watershed'
                    level=graythresh(img);
                    bw=imbinarize(img,level);
                    c=~bw;
                    d=-bwdist(c);
                    d(c)=-Inf;
                    L=watershed(d);
                    if app.FilterDropDown.Value=="ATMED" || app.FilterDropDown.Value=="TMED"
                        imshow(L,[0 700],'parent',app.UIAxes_3);
                    else
                        imshow(L,'parent',app.UIAxes_3);
                    end
                    peaksnr=psnr(uint8(L),img);
                    app.PSNRvaluesegmentationEditField.Value=peaksnr;
                case 'Fuzzy C Segmentation'
                    IM=double(img);

                    [maxX,maxY]=size(IM);
                    IMM=cat(3,IM,IM);
                    %%%%%%%%%%%%%%%%
                    cc1=8;
                    cc2=250;
                    ttFcm=0;
                    while(ttFcm<15)
                        ttFcm=ttFcm+1
                        
                        c1=repmat(cc1,maxX,maxY);
                        c2=repmat(cc2,maxX,maxY);
                        if ttFcm==1 
                            test1=c1; test2=c2;
                        end
                        c=cat(3,c1,c2);
                        
                        ree=repmat(0.000001,maxX,maxY);
                        ree1=cat(3,ree,ree);
                        
                        distance=IMM-c;
                        distance=distance.*distance+ree1;
                        
                        daoShu=1./distance;
                        
                        daoShu2=daoShu(:,:,1)+daoShu(:,:,2);
                        distance1=distance(:,:,1).*daoShu2;
                        u1=1./distance1;
                        distance2=distance(:,:,2).*daoShu2;
                        u2=1./distance2;
                          
                        ccc1=sum(sum(u1.*u1.*IM))/sum(sum(u1.*u1));
                        ccc2=sum(sum(u2.*u2.*IM))/sum(sum(u2.*u2));
                       
                        tmpMatrix=[abs(cc1-ccc1)/cc1,abs(cc2-ccc2)/cc2];
                        pp=cat(3,u1,u2);
                        
                        for i=1:maxX
                            for j=1:maxY
                                if max(pp(i,j,:))==u1(i,j)
                                    IX2(i,j)=1;
                               
                                else
                                    IX2(i,j)=2;
                                end
                            end
                        end
                        %%%%%%%%%%%%%%%
                       if max(tmpMatrix)<0.0001
                             break;
                      else
                             cc1=ccc1;
                             cc2=ccc2;
                            
                      end
                     for i=1:maxX
                           for j=1:maxY
                                if IX2(i,j)==2
                                IMMM(i,j)=254;
                                     else
                                IMMM(i,j)=8;
                           end
                        end
                    end
                     
                    end
                    
                    for i=1:maxX
                        for j=1:maxY
                             if IX2(i,j)==2
                                IMMM(i,j)=200;
                                 else
                                 IMMM(i,j)=1;
                        end
                      end
                    end 
                    
                    L=uint8(IMMM);
                    if app.FilterDropDown.Value=="ATMED" || app.FilterDropDown.Value=="TMED"
                        imshow(L,[0 100],'parent',app.UIAxes_3);
                    else
                        imshow(L,'parent',app.UIAxes_3);
                    end
                    peaks=psnr(uint8(L),uint8(IM));
                    app.PSNRvaluesegmentationEditField.Value=peaks;  
            end
            app.Label.Text="The "+seg_val+" segmentation is applied";
        end

        % Callback function
        function OpenFolderButtonPushed(app, event)
            app.SaveDatainExcelButton.Enable=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltersGUIUIFigure = uifigure('Visible', 'off');
            app.FiltersGUIUIFigure.Name = 'Filters GUI';
            app.openbutton = uibutton(app.FiltersGUIUIFigure, 'push');
            app.openbutton.ButtonPushedFcn = createCallbackFcn(app, @openbuttonButtonPushed, true);
            app.openbutton.Text = 'Open Image';
            app.UIAxes = uiaxes(app.FiltersGUIUIFigure);
            title(app.UIAxes, 'Original Image')
            app.FilterDropDownLabel = uilabel(app.FiltersGUIUIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FiltersGUIUIFigure);
            app.FilterDropDown.Items = {'Mean', 'NAFSM', 'Median', 'Gaussian', 'Wiener', 'LoG', 'ATMED', 'TMED'};
            app.FilterDropDown.Value = 'Mean';
            app.UIAxes_2 = uiaxes(app.FiltersGUIUIFigure);
            title(app.UIAxes_2, ' Image after Filter')
            app.PSNRvaluefilterEditFieldLabel = uilabel(app.FiltersGUIUIFigure);
            app.PSNRvaluefilterEditFieldLabel.Text = 'PSNR value(filter)';
            app.PSNRvaluefilterEditField = uieditfield(app.FiltersGUIUIFigure, 'numeric');
            app.PSNRvaluefilterEditField.Editable = 'off';
            app.SaveDatainExcelButton = uibutton(app.FiltersGUIUIFigure, 'push');
            app.SaveDatainExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatainExcelButtonPushed, true);
            app.SaveDatainExcelButton.Text = 'Save Data in Excel ';
            app.Label = uilabel(app.FiltersGUIUIFigure);
            app.Label.Text = '';
            app.ApplyFilterButton = uibutton(app.FiltersGUIUIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.EditField = uieditfield(app.FiltersGUIUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.SegmentationDropDownLabel = uilabel(app.FiltersGUIUIFigure);
            app.SegmentationDropDownLabel.Text = 'Segmentation';
            app.SegmentationDropDown = uidropdown(app.FiltersGUIUIFigure);
            app.SegmentationDropDown.Items = {'Global/Otsu', 'Watershed ', 'Fuzzy C Segmentation'};
            app.SegmentationDropDown.Value = 'Global/Otsu';
            app.PSNRvaluesegmentationEditFieldLabel = uilabel(app.FiltersGUIUIFigure);
            app.PSNRvaluesegmentationEditFieldLabel.Text = 'PSNR value(segmentation)';
            app.PSNRvaluesegmentationEditField = uieditfield(app.FiltersGUIUIFigure, 'numeric');
            app.ApplysegmentationButton = uibutton(app.FiltersGUIUIFigure, 'push');
            app.ApplysegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysegmentationButtonPushed, true);
            app.ApplysegmentationButton.Text = 'Apply segmentation';
            app.UIAxes_3 = uiaxes(app.FiltersGUIUIFigure);
            title(app.UIAxes_3, ' Image after segmentation')
            % Show the figure after all components are created
            app.FiltersGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filter_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltersGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltersGUIUIFigure)
        end
    end
end